classdef wp1_milestone1_matlab_appdesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VoltageVEditField             matlab.ui.control.NumericEditField
        VoltageVEditFieldLabel        matlab.ui.control.Label
        CurrentAEditField             matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel        matlab.ui.control.Label
        MotorusageEditField_2         matlab.ui.control.NumericEditField
        MotorusageEditField_2Label    matlab.ui.control.Label
        PowerconsumptionWEditField_2  matlab.ui.control.NumericEditField
        PowerconsumptionWEditField_2Label  matlab.ui.control.Label
        LiftforcegEditField_2         matlab.ui.control.NumericEditField
        LiftforcegEditField_2Label    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        userInput = ''% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            h1 = animatedline(app.UIAxes, 'Color','b','LineWidth',2, 'MaximumNumPoints',500);
            h1.DisplayName = 'Load cell (g)';
            h2 = animatedline(app.UIAxes, 'Color','r','LineWidth',2, 'MaximumNumPoints',500);
            h2.DisplayName = 'Power consumption (W)';

            s = serialport('COM3', 9600);
            configureTerminator(s, "CR/LF");
            
            % Use this code for real-time data vizualisation (plot angle vs time)
            clc; close all; flush(s);
            ax = app.UIAxes; % Get axis of the graph

            legend(app.UIAxes, 'Location', 'northeastoutside'); % Turn on legend

            drawnow

            while 1
                % write to serial
                if (~strcmp(app.userInput, ''))
                    writeline(s, app.userInput);
                    app.userInput = '';
                end
                app.userInput = '';

                %read from serial communication
                %do NOT add a semicolon so we can see its outputs via the command window
                string = readline(s);
                %scans the string for inputs: %f 
                %double %% means one % in the string
                if (size(string) == 0)
                    continue;
                end
                disp(string)
                data = sscanf(string, "Loadcell: %f g\nMotor usage: %f%%\nCurrent: %f A\nVoltage: %f V\nPower: %f W\nTime: %f\n");
                %make sure the data is correct i.e. 6 outputs is extracted from string
                if (size(data) < 6) 
                    continue;
                end
                %assign the variables
                loadcell = data(1);
                motorUsage = data(2);
                current = data(3);
                voltage = data(4);
                power = data(5);
                time = data(6);
                
                %add fields to app value
                app.MotorusageEditField_2.Value = motorUsage;
                app.LiftforcegEditField_2.Value = loadcell;
                app.PowerconsumptionWEditField_2.Value = power;
                app.CurrentAEditField.Value = current;
                app.VoltageVEditField.Value = voltage;

                %set xlim to move our graph horizontally
                xlim(ax, [max(0, time/1000 - 10), time/1000 + 10]);
                %set ylim to resize our graph upward
                ylim(ax, [-10, min(250, round(loadcell/40)*40 + 40)]);
                
                %add points to the corresponding lines
                addpoints(h1, time/1000, loadcell)
                addpoints(h2, time/1000, power)
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Lift Force and Power Consumption')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Value')
            zlabel(app.UIAxes, 'Z')
            app.LiftforcegEditField_2Label = uilabel(app.UIFigure);
            app.LiftforcegEditField_2Label.Text = 'Lift force (g)';
            app.LiftforcegEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LiftforcegEditField_2.ValueDisplayFormat = '%.2f';
            app.PowerconsumptionWEditField_2Label = uilabel(app.UIFigure);
            app.PowerconsumptionWEditField_2Label.Text = 'Power consumption (W)';
            app.PowerconsumptionWEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PowerconsumptionWEditField_2.ValueDisplayFormat = '%.2f';
            app.MotorusageEditField_2Label = uilabel(app.UIFigure);
            app.MotorusageEditField_2Label.Text = 'Motor usage (%)';
            app.MotorusageEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MotorusageEditField_2.ValueDisplayFormat = '%.2f';
            app.CurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentAEditFieldLabel.Text = 'Current (A)';
            app.CurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentAEditField.ValueDisplayFormat = '%.2f';
            app.VoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageVEditFieldLabel.Text = 'Voltage (V)';
            app.VoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageVEditField.ValueDisplayFormat = '%.2f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wp1_milestone1_matlab_appdesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end